classdef bubble < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Microbubble_thermal_simaulation_program  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SinglebubbleTab                 matlab.ui.container.Tab
        HIFUparameterPanel_2            matlab.ui.container.Panel
        frequencyEditField_2Label       matlab.ui.control.Label
        frequency_single                matlab.ui.control.NumericEditField
        pressureEditField_2Label        matlab.ui.control.Label
        pressure_single                 matlab.ui.control.NumericEditField
        MPaLabel_3                      matlab.ui.control.Label
        MHzLabel_2                      matlab.ui.control.Label
        MediumparameterPanel_3          matlab.ui.container.Panel
        velocityLabel_2                 matlab.ui.control.Label
        velosity_single                 matlab.ui.control.NumericEditField
        RhoEditField_2Label             matlab.ui.control.Label
        Rho_single                      matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        MraylLabel_2                    matlab.ui.control.Label
        impedanceEditField_2Label       matlab.ui.control.Label
        impedance_single                matlab.ui.control.NumericEditField
        JKLabel_2                       matlab.ui.control.Label
        WmKLabel_2                      matlab.ui.control.Label
        NapperLabel_2                   matlab.ui.control.Label
        conductivityEditField_3Label    matlab.ui.control.Label
        conductivity_single             matlab.ui.control.NumericEditField
        specificheatEditField_2Label    matlab.ui.control.Label
        specificheat_single             matlab.ui.control.NumericEditField
        attenuationEditField_2Label     matlab.ui.control.Label
        attenuation_single              matlab.ui.control.NumericEditField
        MediumparameterPanel_4          matlab.ui.container.Panel
        gasexponentEditField_2Label     matlab.ui.control.Label
        gasexponen_single               matlab.ui.control.NumericEditField
        surfacetensionEditField_2Label  matlab.ui.control.Label
        surfacetension_single           matlab.ui.control.NumericEditField
        NmLabel_2                       matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        shearviscosityEditField_2Label  matlab.ui.control.Label
        shearviscosity_single           matlab.ui.control.NumericEditField
        umLabel_2                       matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        umLabel_3                       matlab.ui.control.Label
        radiusEditField_2Label          matlab.ui.control.Label
        radius_single                   matlab.ui.control.NumericEditField
        distanceEditFieldLabel          matlab.ui.control.Label
        distance_single                 matlab.ui.control.NumericEditField
        startButton_single              matlab.ui.control.Button
        BubblecloudTab                  matlab.ui.container.Tab
        HIFUparameterPanel              matlab.ui.container.Panel
        frequencyEditFieldLabel         matlab.ui.control.Label
        frequency_cloud                 matlab.ui.control.NumericEditField
        pressureEditFieldLabel          matlab.ui.control.Label
        pressure_cloud                  matlab.ui.control.NumericEditField
        MPaLabel                        matlab.ui.control.Label
        MHzLabel                        matlab.ui.control.Label
        MediumparameterPanel            matlab.ui.container.Panel
        velocityLabel                   matlab.ui.control.Label
        velosity_cloud                  matlab.ui.control.NumericEditField
        rhoLabel                        matlab.ui.control.Label
        Rho_cloud                       matlab.ui.control.NumericEditField
        kgm3Label                       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        raylLabel                       matlab.ui.control.Label
        impedanceEditFieldLabel         matlab.ui.control.Label
        impedance_cloud                 matlab.ui.control.NumericEditField
        JKLabel                         matlab.ui.control.Label
        WmKLabel                        matlab.ui.control.Label
        NapperLabel                     matlab.ui.control.Label
        conductivityEditFieldLabel      matlab.ui.control.Label
        conductivity_cloud              matlab.ui.control.NumericEditField
        specificheatEditFieldLabel      matlab.ui.control.Label
        specificheat_cloud              matlab.ui.control.NumericEditField
        attenuationEditFieldLabel       matlab.ui.control.Label
        attenuationEditField            matlab.ui.control.NumericEditField
        MediumparameterPanel_2          matlab.ui.container.Panel
        gasexponentEditFieldLabel       matlab.ui.control.Label
        gasexponent_cloud               matlab.ui.control.NumericEditField
        surfacetensionEditFieldLabel    matlab.ui.control.Label
        surfacetension_cloud            matlab.ui.control.NumericEditField
        NmLabel                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        shearviscosityEditFieldLabel    matlab.ui.control.Label
        shearviscosity_cloud            matlab.ui.control.NumericEditField
        umLabel                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        solutionratioEditFieldLabel     matlab.ui.control.Label
        solutionratio_cloud             matlab.ui.control.NumericEditField
        radiusEditFieldLabel            matlab.ui.control.Label
        radius_cloud                    matlab.ui.control.NumericEditField
        SphereradiusEditFieldLabel      matlab.ui.control.Label
        Sphereradius_cloud              matlab.ui.control.NumericEditField
        CubedistanceEditFieldLabel      matlab.ui.control.Label
        Cubedistance_cloud              matlab.ui.control.NumericEditField
        startButton_cloud               matlab.ui.control.Button
        SpherepressureLabel             matlab.ui.control.Label
        Spherepressure_cloud            matlab.ui.control.NumericEditField
        CubepressureLabel               matlab.ui.control.Label
        Cubepressure_cloud              matlab.ui.control.NumericEditField
        umLabel_4                       matlab.ui.control.Label
        umLabel_5                       matlab.ui.control.Label
        MPaLabel_4                      matlab.ui.control.Label
        MPaLabel_5                      matlab.ui.control.Label
        changeButton                    matlab.ui.control.Button
        imagesavingFolderEditFieldLabel  matlab.ui.control.Label
        imagesavingFolder               matlab.ui.control.EditField
        sLabel                          matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        SphereelapsedtimeLabel          matlab.ui.control.Label
        Sphereelapsedtime_cloud         matlab.ui.control.NumericEditField
        CubeelapsedtimeLabel            matlab.ui.control.Label
        Cubeelapsedtim_cloud            matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        ref = 0; % Description
    end
    
    methods (Access = private)
        
        function Data = PulseF(app,dt,R0,Pm,Cycle,r, Rho, c0, f)
            T=1/4E+6; CycleT=T*Cycle;
            t=0:dt:CycleT+dt; % time vector
            
            % Extra condition 
            Pa = Pm*sin(2*pi*f*t);
            R=[]; dR=[]; d2R=[]; H=[];
            R(1)=R0; dR(1)=0; % Initial condition
            N=(CycleT+dt)/dt;
            
            % SonoVue parameter
            Gamma = app.surfacetension_single.Value;       % The surface tension
            Sigma = app.gasexponen_single.Value;      % The polytropic gas exponent
            u = app.shearviscosity_single.Value;            % Shear viscosity
            n = 7;              % Water ratio of heat capacibity
            P0 = 101300;        % The ambient pressure of the surronding liquid
            A = ((c0)^2)*Rho/n;  
            B = A - P0;
            
            rr = r;

            for i=1:N
                %Pg = (P0 + 2*Sigma/R(i))*((R0/R(i))^(3*Gamma)); % Pressure of the gas inside the bubble
                %Pb = Pg-(2*Sigma/R(i))-(4*u*((dR(i))^2)/R(i)); % Pressure at the bubble wall
                % Runge-Kutta 6th order  1.1785E3
                % Point1
                h1=(n/(n-1))*((A^(1/n))/Rho)*(((P0+(2*Sigma/R(i)))*((R0/R(i))^(3*Gamma))-(4*u*((dR(i))^2)/R(i))-(2*Sigma/R(i))+B)^((n-1)/n)-(P0+5E2+B)^((n-1)/n));
                c1=((c0^2)+(n-1)*h1)^(0.5); % Speed of sound
                Zeta1=((1+(dR(i)/c1))*h1-1.5*(1-(dR(i)/(3*c1)))*(dR(i)*dR(i)))/(R(i)*(1-(dR(i)/c1)));
                Alpha1=((P0+(2*Sigma/R0))*((R0/R(i))^(3*Gamma))-(2*Sigma/R(i))-(4*u*((dR(i))^2)/R(i))+B)^(-1/n);
                Beta1=(-2*Sigma/(R(i)*R(i)))*((R0/R(i))^(3*Gamma))-3*Gamma*((P0+(2*Sigma/R(i)))*((R0/R(i))^(3*Gamma)))*(1/R(i))+(2*Sigma/(R(i)*R(i)))+4*u*(dR(i)*dR(i)/(R(i)*R(i)));
                d2R1= (Zeta1+(dR(i)/c1)*((A^(1/n))/(Rho))*Alpha1*Beta1)/(1+8*u*((A^(1/n))/(Rho))*Alpha1*dR(i)/(c1*R(i))); % Acceleration of Radius
    
                R2=R(i)+(dR(i)*(dt/4));
                dR2=dR(i)+(d2R1*(dt/4));
   
                % Point 2
                h2=((n/(n-1))*(A^(1/n))/Rho)*((((P0+(2*Sigma/R2))*((R0/R2)^(3*Gamma))-(4*u*((dR2)^2)/R2)-(2*Sigma/R2)+B)^((n-1)/n))-(P0+5E2+B)^((n-1)/n)); %Enthalpy
                c2=((c0^2)+(n-1)*h2)^(0.5); % Speed of sound
                Zeta2=((1+(dR2/c2))*h2-1.5*(1-(dR2/(3*c2)))*(dR2*dR2))/(R2*(1-(dR2/c2)));
                Alpha2=((P0 + (2*Sigma/R0))*((R0/R2)^(3*Gamma))-(2*Sigma/R2)-(4*u*((dR2)^2)/R2)+B)^(-1/n);
                Beta2=(-2*Sigma/(R2*R2))*((R0/R2)^(3*Gamma))-3*Gamma*((P0 + (2*Sigma/R2))*((R0/R2)^(3*Gamma)))*(1/R2)+(2*Sigma/(R2*R2))+4*u*((dR2*dR2)/(R2*R2));
                d2R2=(Zeta2+(dR2/c2)*((A^(1/n))/(Rho))*Alpha2*Beta2)/(1+8*u*((A^(1/n))/(Rho))*Alpha2*dR2/(c2*R2));% Acceleration of Radius
    
                R3=R(i)+(3/32)*(dR(i)+3*dR2)*dt;
                dR3=dR(i)+(3/32)*(d2R1+3*d2R2)*dt;
    
                %Point 3
                h3=((n/(n-1))*(A^(1/n))/Rho)*(((P0+(2*Sigma/R3))*((R0/R3)^(3*Gamma))-(4*u*((dR3)^2)/R3)-(2*Sigma/R3)+B)^((n-1)/n)-(P0+5E2+B)^((n-1)/n)); %Enthalpy
                c3=((c0^2)+(n-1)*h3)^(0.5); % Speed of sound
                Zeta3=((1+(dR3/c3))*h3-1.5*(1-(dR3/(3*c3)))*(dR3*dR3))/(R3*(1-(dR3/c3)));
                Alpha3=((P0+(2*Sigma/R0))*((R0/R3)^(3*Gamma))-(2*Sigma/R3)-4*u*(((dR3)^2)/R3)+B)^(-1/n);
                Beta3=(-2*Sigma/(R3*R3))*((R0/R3)^(3*Gamma))-3*Gamma*(P0+(2*Sigma/R3))*((R0/R3)^(3*Gamma))*(1/R3)+(2*Sigma/(R3*R3))+4*u*((dR3*dR3)/(R3*R3));
                d2R3=(Zeta3+(dR3/c3)*((A^(1/n))/(Rho))*Alpha3*Beta3)/(1+4*u*((A^(1/n))/(Rho))*Alpha3/(c3*R3));% Acceleration of Radius
    
                R4=R(i)+(12/2197)*(161*dR(i)-600*dR2+608*dR3)*dt ;
                dR4=dR(i)+(12/2197)*(161*d2R1-600*d2R2+608*d2R3)*dt;
    
                % Point 4
                h4=((n/(n-1))*(A^(1/n))/Rho)*(((P0+(2*Sigma/R4))*((R0/R4)^(3*Gamma))-(4*u*((dR4)^2)/R4)-(2*Sigma/R4)+B)^((n-1)/n)-(P0+5E2+B)^((n-1)/n)); %Enthalpy
                c4=((c0^2)+(n-1)*h4)^(0.5); % Speed of sound
                Zeta4=((1+(dR4/c4))*h4-1.5*(1-(dR4/(3*c4)))*(dR4*dR4))/(R4*(1-(dR4/c4)));
                Alpha4=((P0+(2*Sigma/R0))*((R0/R4)^(3*Gamma))-(2*Sigma/R4)-4*u*(((dR4)^2)/R4)+B)^(-1/n);
                Beta4=(-2*Sigma/(R4*R4))*((R0/R4)^(3*Gamma))-3*Gamma*(P0+(2*Sigma/R4))*((R0/R4)^(3*Gamma))*(1/R4)+(2*Sigma/(R4*R4))+4*u*((dR4*dR4)/(R4*R4));
                d2R4=(Zeta4+(dR4/c4)*((A^(1/n))/(Rho))*Alpha4*Beta4)/(1+8*u*((A^(1/n))/(Rho))*Alpha4*dR4/(c4*R4));% Acceleration of Radius
    
                R5=R(i)+(1/4104)*(8341*dR(i)-32832*dR2+29440*dR3-845*dR4)*dt;
                dR5=dR(i)+(1/4104)*(8341*d2R1-32832*d2R2+29440*d2R3-845*d2R4)*dt;
    
                % Point 5
                h5=((n/(n-1))*(A^(1/n))/Rho)*(((P0+(2*Sigma/R5))*((R0/R5)^(3*Gamma))-(4*u*((dR5)^2)/R5)-(2*Sigma/R5)+B)^((n-1)/n)-(P0+5E2+B)^((n-1)/n)); %Enthalpy
                c5=((c0^2)+(n-1)*h5)^(0.5); % Speed of sound
                Zeta5=((1+(dR5/c5))*h5-1.5*(1-(dR5/(3*c5)))*(dR5*dR5))/(R5*(1-(dR5/c5)));
                Alpha5=((P0+(2*Sigma/R0))*((R0/R5)^(3*Gamma))-(2*Sigma/R5)-4*u*(((dR5)^2)/R5)+B)^(-1/n);
                Beta5=(-2*Sigma/(R5*R5))*((R0/R5)^(3*Gamma))-3*Gamma*(P0+(2*Sigma/R5))*((R0/R5)^(3*Gamma))*(1/R5)+(2*Sigma/(R5*R5))+4*u*((dR5*dR5)/(R5*R5));
                d2R5=(Zeta5+(dR5/c5)*((A^(1/n))/(Rho))*Alpha5*Beta5)/(1+8*u*((A^(1/n))/(Rho))*Alpha5*dR5/(c5*R5));% Acceleration of Radius
    
                R6=R(i)+(-(8/27)*dR(i)+2*dR2-(3544/2565)*dR3+(1859/4104)*dR4-(11/40)*dR5)*dt;
                dR6=dR(i)+(-(8/27)*d2R1+2*d2R2-(3544/2565)*d2R3+(1859/4104)*d2R4-(11/40)*d2R5)*dt;
   
                % Point 6
                h6=((n/(n-1))*(A^(1/n))/Rho)*(((P0+(2*Sigma/R6))*((R0/R6)^(3*Gamma))-(4*u*((dR6)^2)/R6)-(2*Sigma/R6)+B)^((n-1)/n)-(P0+5E2+B)^((n-1)/n)); %Enthalpy
                c6=((c0^2)+(n-1)*h6)^(0.5); % Speed of sound
                Zeta6=((1+(dR6/c6))*h6-1.5*(1-(dR6/(3*c6)))*(dR6*dR6))/(R6*(1-(dR6/c6)));
                Alpha6=((P0+(2*Sigma/R0))*((R0/R6)^(3*Gamma))-(2*Sigma/R6)-4*u*(((dR6)^2)/R6)+B)^(-1/n);
                Beta6=(-2*Sigma/(R6*R6))*((R0/R6)^(3*Gamma))-3*Gamma*(P0+(2*Sigma/R6))*((R0/R6)^(3*Gamma))*(1/R6)+(2*Sigma/(R6*R6))+4*u*((dR6*dR6)/(R6*R6));
                d2R6=(Zeta6+(dR6/c6)*((A^(1/n))/(Rho))*Alpha6*Beta6)/(1+8*u*((A^(1/n))/(Rho))*Alpha6*dR6/(c6*R6));% Acceleration of Radius
    
         
                k=(1/5)*((16/27)*dR(i)+(6656/2565)*dR3+(28561/11286)*dR4-(9/10)*dR5+(2/11)*dR6);
                kdot= (1/5)*((16/27)*d2R1+(6656/2565)*d2R3+(28561/11286)*d2R4-(9/10)*d2R5+(2/11)*d2R6);
    
                r=R(i) + k*dt;
                dr=dR(i) + kdot*dt;     
    
                R=[R r]; dR=[dR dr]; d2R=[d2R d2R1]; H=[H h1];
    
            end
            
            H=[H 0];
            
            dx=(2E-3)/N;
            x=-1E-3:dx:1E-3;

            t=0:dt:dt*(length(R)-1);
            Ps=[];

            for i=1:length(R)
                G=R(i)*(H(i)+((dR(i)*dR(i))/2));
                cl=((c0^2)+(n-1)*H(i))^(0.5);
                ps=A*((((2/(n+1))+((n-1)/(n+1))*((1+((n+1)*G/(rr*((cl)^2))))^(1/2))))^(2*n/(n-1)))-B-P0;
                Ps=[Ps ps];
            end
            Rmin=min(R); Hmax=max(H); Umax=max(dR); 
            Gmax=Rmin*(Hmax+0.5*Umax*Umax);

            dx=(2E-3)/N;
            x=-1E-3:dx:1E-3;
            Pr=[];
            for i=1:length(R)
                pr=A*(((2/(n+1))+((n-1)/(n+1))*(1+(n+1)*Gmax/(((x(i)^2+rr^2)^(0.5))*c0))^(0.5)))^(2*n/(n-1))-B-P0;
                Pr=[Pr pr];
            end

            Ps=abs(Ps);
            Pa=abs(Pa);

            Data=[t',Ps',Pr',R',x'];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.imagesavingFolder.Value = pwd;
        end

        % Button pushed function: startButton_single
        function startButton_singlePushed(app, event)
            %% inital setting
            dt =1E-8; %샘플링 간격(s)
            R0 = app.radius_single.Value*1e-6; %기포 반경(m)
            Pm = app.pressure_single.Value*1e+6; % 압력진폭
            Cycle =10000; %보고자 하는 시간길이
            Rho = app.Rho_single.Value;
            c0 = app.velosity_single.Value;
            f = app.frequency_single.Value*1e+6;
            r = app.distance_single.Value*1e-6;
            
            pa = PulseF(app,dt,R0,Pm,Cycle,r, Rho, c0, f);
            
            
            tem_med = 25;
            temp_present = 25;
            
            tem_med_0 = 25;
            temp_present_0 = 25;

            len = length(pa(:,1));
            temp_dif = zeros(1,len*8);
            temp_d = zeros(1,len*8);
            
            temp_dif_0 = zeros(1,len*8);
            temp_d_0 = zeros(1,len*8);
            
            impedance =app.impedance_single.Value;
            qb = (((pa(:,2)).^2)/impedance)*2*app.attenuation_single.Value;
            qh = (((Pm).^2)/impedance)*2*app.attenuation_single.Value;
            q = qb+qh;

            specific_heat = app.specificheat_single.Value;
            conduc_med = app.conductivity_single.Value;
    
            for i = 1:len
                q=(qh*232+qb(i)*6); 
                q_0 = (qh*232); 
                
                under=(Rho*specific_heat);
                TT = (tem_med-temp_present)*conduc_med*232;
                TT_0 = (tem_med_0-temp_present_0)*conduc_med*232;
                
                temp_dif(i)=((q/under)+TT)*dt;
                temp_present=temp_present+temp_dif(i);
                temp_d(i)=temp_present;
                
                temp_dif_0(i)=((q_0/under)+TT_0)*dt;
                temp_present_0=temp_present_0+temp_dif_0(i);
                temp_d_0(i)=temp_present_0;
            end
            for i = len+1:len*8
                temp_dif(i)=((tem_med-temp_present)*conduc_med)*dt*232;
                temp_present=temp_present+temp_dif(i);
                temp_d(i)=temp_present;
                
                temp_dif_0(i)=((tem_med_0-temp_present_0)*conduc_med)*dt*232;
                temp_present_0=temp_present_0+temp_dif_0(i);
                temp_d_0(i)=temp_present_0;
    
            end
            figure('Name','Temp_Single','NumberTitle','off')
            time_vec = [0:dt:len*8*dt-dt]*232;
            [pks,locs] = findpeaks(temp_d,'MinPeakDistance',1000);
            plot(time_vec,temp_d,time_vec(locs),pks,'or'); xlabel('Time : S'); ylabel('Temperature : °C');
            text(time_vec(locs)+0.2,pks,num2str(pks));
            
            plot_name = sprintf('Temp_fig_Single %0.1f_.png',r);
            saveas(gca,[app.imagesavingFolder.Value,'\',plot_name])
            
            figure('Name','Temp_original','NumberTitle','off')
            [pks,locs] = findpeaks(temp_d_0,'MinPeakDistance',1000);
            plot(time_vec,temp_d_0,time_vec(locs),pks,'or'); xlabel('Time : S'); ylabel('Temperature : °C');
            text(time_vec(locs)+0.2,pks,num2str(pks));

            saveas(gca,[app.imagesavingFolder.Value,'\Temp_fig_Original_.png'])
            
            figure('Name','Pressure Change Over Distance','NumberTitle','off')
            plot(pa(:,5),pa(:,3)); xlabel('Distance : m'); ylabel('Pressure : Pa');

            saveas(gca,[app.imagesavingFolder.Value,'\Pressure Change Over Distance.png'])
            
            figure('Name','Radius Change Over Time','NumberTitle','off')
            plot(pa(1:10000,1),pa(1:10000,4)); xlabel('Time : S'); ylabel('Radius : m');

            saveas(gca,[app.imagesavingFolder.Value,'\Radius Change Over Time.png'])
        end

        % Button pushed function: startButton_cloud
        function startButton_cloudPushed(app, event)
            app.changeButton.Enable = false;
            app.imagesavingFolder.Enable = false;
            
            %% inital setting
            dt =1E-8; %샘플링 간격(s)
            R0 = app.radius_cloud.Value*1e-6; %기포 반경(m)
            Pm = app.pressure_cloud.Value*1e+6; % 압력진폭
            Cycle =100; %보고자 하는 시간길이
            Rho = app.Rho_cloud.Value;
            c0 = app.velosity_cloud.Value;
            f = app.frequency_cloud.Value*1e+6;
            
            raw =(3E+8)*app.solutionratio_cloud.Value*0.01;
            rad = (0.01/nthroot(raw,3))*1E+6;
                        
            if app.ref == 0
                cube_radi = (rad/2 :rad:app.Cubedistance_cloud.Value);
                x = length(cube_radi);
                dist_radi = (rad*sqrt(3/(2*pi)):rad*2*sqrt(3/(2*pi)):app.Spherepressure_cloud.Value);
                value = app.Spherepressure_cloud.Value;
                while x ~= length(dist_radi)
                    if x > length(dist_radi)
                        value= value+1;
                    elseif x < length(dist_radi)
                        value= value-1;
                    end
                   dist_radi = (rad*sqrt(3/(2*pi)):rad*2*sqrt(3/(2*pi)):value);
                   length(dist_radi);
                end
            else
                dist_radi = (rad*sqrt(3/(2*pi)):rad*2*sqrt(3/(2*pi)):app.Spherepressure_cloud.Value);
                x = length(dist_radi);
                cube_radi = (rad/2 :rad:app.Cubedistance_cloud.Value);
                value = app.Cubedistance_cloud.Value;
                while x ~= length(cube_radi)
                    if x > length(cube_radi)
                        value= value+1;
                    else
                        value= value-1;
                    end
                   cube_radi = (rad :rad*2:value);
                   length(cube_radi);
                end
            end
            
            %% cube
            tic
            Vec_len = x.^3;
            proc_Vec = zeros(Vec_len,3);
            N = 1;
            proc_Eclu = zeros(Vec_len,1);
            
            for i = 1:x
                for j = 1:x
                    for k = 1:x
                        proc_Vec(N,1) =  cube_radi(i);
                        proc_Vec(N,2) =  cube_radi(j);
                        proc_Vec(N,3) =  cube_radi(k);
                        proc_Eclu(N,1) = norm(proc_Vec(N,:));
                        N = N+1;
                    end
                end
            end

            uni_proc_Eclu = unique(proc_Eclu);
            
            wai = waitbar(0,'Please wait...');

            Pa_sum = zeros(2502,2);
            formatSpec = 'Loading your data %0.2f %%';

            for L = 1:length(uni_proc_Eclu)
                r = uni_proc_Eclu(L)*1E-6;
                k = length(find(proc_Eclu == uni_proc_Eclu(L)));
                Pa=PulseF_CC(dt,R0,Pm,Cycle,r, Rho, c0, f);
    
                Pa_sum = Pa_sum+(Pa(:,2:3)*k);
                str = sprintf(formatSpec,(L/length(uni_proc_Eclu))*100);
    
                waitbar(L/length(uni_proc_Eclu),wai,str);

            end
            close(wai);
            toc
            app.Cubeelapsedtim_cloud.Value = toc;
            app.Cubepressure_cloud.Value = (max(Pa_sum(:,1))*8)/1E+6;
            
            tem_med = 25;
            temp_present = 25;
            
            tem_med_0 = 25;
            temp_present_0 = 25;

            len = length(Pa_sum(:,1));
            temp_dif = zeros(1,len*8);
            temp_d = zeros(1,len*8);
            
            temp_dif_0 = zeros(1,len*8);
            temp_d_0 = zeros(1,len*8);
            
            impedance =app.impedance_single.Value;
            qb = (((Pa_sum(:,1)*8).^2)/impedance)*2*app.attenuation_single.Value;
            qh = (((Pm).^2)/impedance)*2*app.attenuation_single.Value;
            q = qb+qh;

            specific_heat = app.specificheat_single.Value;
            conduc_med = app.conductivity_single.Value;
    
            for i = 1:len
                q=(qh*23200+qb(i)*6); 
                q_0 = (qh*23200);
                
                under=(Rho*specific_heat);
                TT = (tem_med-temp_present)*conduc_med*23200;
                TT_0 = (tem_med_0-temp_present_0)*conduc_med*23200;
                
                temp_dif(i)=((q/under)+TT)*dt;
                temp_present=temp_present+temp_dif(i);
                temp_d(i)=temp_present;
                
                temp_dif_0(i)=((q_0/under)+TT_0)*dt;
                temp_present_0=temp_present_0+temp_dif_0(i);
                temp_d_0(i)=temp_present_0;
            end
            for i = len+1:len*8
                temp_dif(i)=((tem_med-temp_present)*conduc_med)*dt*23200;
                temp_present=temp_present+temp_dif(i);
                temp_d(i)=temp_present;
                
                temp_dif_0(i)=((tem_med_0-temp_present_0)*conduc_med)*dt*23200;
                temp_present_0=temp_present_0+temp_dif_0(i);
                temp_d_0(i)=temp_present_0;
    
            end
            time_vec = [0:dt:len*8*dt-dt]*23200;
            figure('Name','Temp_Cube','NumberTitle','off')
            [pks,locs] = findpeaks(temp_d,'MinPeakDistance',1000);
            plot(time_vec,temp_d,time_vec(locs),pks,'or'); xlabel('Time : S'); ylabel('Temperature : °C');
            text(time_vec(locs)+0.2,pks,num2str(pks));

            plot_name = sprintf('Temp_fig_Cube %0.1f_.png',app.solutionratio_cloud.Value);
            saveas(gca,[app.imagesavingFolder.Value,'\',plot_name])
            
            figure('Name','Temp_Original','NumberTitle','off')
            [pks,locs] = findpeaks(temp_d_0,'MinPeakDistance',1000);
            plot(time_vec,temp_d_0,time_vec(locs),pks,'or');xlabel('Time : S'); ylabel('Temperature : °C');
            text(time_vec(locs)+0.2,pks,num2str(pks));

            saveas(gca,[app.imagesavingFolder.Value,'\Temp_fig_Original.png'])
            %% circle
            tic
            num = (1: x);
            caviti_num = zeros(1,x);
    
            for i = num
                caviti_num(i) = 24*i^2-24*i+8;
            end
            wai = waitbar(0,'Please wait...');

            Pa_sum = zeros(2502,2);
            formatSpec = 'Loading your data %0.2f %%';

            for L = num
                r = dist_radi(L)*1E-6;
                k = caviti_num(L);
                Pa=PulseF_CC(dt,R0,Pm,Cycle,r, Rho, c0, f);
    
                Pa_sum = Pa_sum+(Pa(:,2:3)*k);
                str = sprintf(formatSpec,(L/length(num))*100);
    
                waitbar(L/length(num),wai,str);
            end

            close(wai)
            toc
            app.Sphereelapsedtime_cloud.Value = toc;
            app.Spherepressure_cloud.Value = max(Pa_sum(:,1))/1E+6;
            
            tem_med = 25;
            temp_present = 25;

            len = length(Pa_sum(:,1));
            temp_dif = zeros(1,len*8);
            temp_d = zeros(1,len*8);
            
            
            impedance =app.impedance_single.Value;
            qb = (((Pa_sum(:,1)).^2)/impedance)*2*app.attenuation_single.Value;
            qh = (((Pm).^2)/impedance)*2*app.attenuation_single.Value;
            q = qb+qh;

            specific_heat = app.specificheat_single.Value;
            conduc_med = app.conductivity_single.Value;
    
            for i = 1:len
                q=(qh*23200+qb(i)*6); 
                
                under=(Rho*specific_heat);
                TT = (tem_med-temp_present)*conduc_med*23200;
                
                temp_dif(i)=((q/under)+TT)*dt;
                temp_present=temp_present+temp_dif(i);
                temp_d(i)=temp_present;
                
            end
            for i = len+1:len*8
                temp_dif(i)=((tem_med-temp_present)*conduc_med)*dt*23200;
                temp_present=temp_present+temp_dif(i);
                temp_d(i)=temp_present;
    
            end
            
            app.changeButton.Enable = true;
            app.imagesavingFolder.Enable = true;
            
            figure('Name','Temp_Sphere','NumberTitle','off')
            [pks,locs] = findpeaks(temp_d,'MinPeakDistance',1000);
            plot(time_vec,temp_d,time_vec(locs),pks,'or');
            text(time_vec(locs)+0.2,pks,num2str(pks));

            plot_name = sprintf('Temp_fig_Sphere %0.1f_.png',app.solutionratio_cloud.Value); xlabel('Time : S'); ylabel('Temperature : °C');
            saveas(gca,[app.imagesavingFolder.Value,'\',plot_name]) 
        end

        % Value changed function: Sphereradius_cloud
        function Sphereradius_cloudValueChanged(app, event)
            value = app.Sphereradius_cloud.Value;
            app.Cubedistance_cloud.Value=round(value*sqrt((2*pi)/3))/2;
            app.ref = 1;
        end

        % Value changed function: Cubedistance_cloud
        function Cubedistance_cloudValueChanged(app, event)
            value = app.Cubedistance_cloud.Value;
            app.Sphereradius_cloud.Value=round(value*sqrt(3/(2*pi)))*2;
            app.ref = 0;
        end

        % Button pushed function: changeButton
        function changeButtonPushed(app, event)
            app.imagesavingFolder.Value = uigetdir;
        end

        % Close request function: 
        % Microbubble_thermal_simaulation_program
        function Microbubble_thermal_simaulation_programCloseRequest(app, event)
            delete(app)
            close all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Microbubble_thermal_simaulation_program = uifigure('Visible', 'off');
            app.Microbubble_thermal_simaulation_program.Name = 'Microbubble Thermal  Simulation Program';
            app.Microbubble_thermal_simaulation_program.CloseRequestFcn = createCallbackFcn(app, @Microbubble_thermal_simaulation_programCloseRequest, true);
            app.TabGroup = uitabgroup(app.Microbubble_thermal_simaulation_program);
            app.SinglebubbleTab = uitab(app.TabGroup);
            app.SinglebubbleTab.Title = 'Single bubble';
            app.HIFUparameterPanel_2 = uipanel(app.SinglebubbleTab);
            app.HIFUparameterPanel_2.Title = 'HIFU parameter';
            app.frequencyEditField_2Label = uilabel(app.HIFUparameterPanel_2);
            app.frequencyEditField_2Label.Text = 'frequency';
            app.frequency_single = uieditfield(app.HIFUparameterPanel_2, 'numeric');
            app.frequency_single.Value = 4;
            app.pressureEditField_2Label = uilabel(app.HIFUparameterPanel_2);
            app.pressureEditField_2Label.Text = 'pressure';
            app.pressure_single = uieditfield(app.HIFUparameterPanel_2, 'numeric');
            app.pressure_single.Value = 1.2;
            app.MPaLabel_3 = uilabel(app.HIFUparameterPanel_2);
            app.MPaLabel_3.Text = 'MPa';
            app.MHzLabel_2 = uilabel(app.HIFUparameterPanel_2);
            app.MHzLabel_2.Text = 'MHz';
            app.MediumparameterPanel_3 = uipanel(app.SinglebubbleTab);
            app.MediumparameterPanel_3.Title = 'Medium parameter';
            app.velocityLabel_2 = uilabel(app.MediumparameterPanel_3);
            app.velocityLabel_2.Text = 'velocity';
            app.velosity_single = uieditfield(app.MediumparameterPanel_3, 'numeric');
            app.velosity_single.Value = 1550;
            app.RhoEditField_2Label = uilabel(app.MediumparameterPanel_3);
            app.RhoEditField_2Label.Text = 'Rho';
            app.Rho_single = uieditfield(app.MediumparameterPanel_3, 'numeric');
            app.Rho_single.Value = 1055;
            app.Label_8 = uilabel(app.MediumparameterPanel_3);
            app.Label_8.Text = '';
            app.msLabel_2 = uilabel(app.MediumparameterPanel_3);
            app.msLabel_2.Text = 'm/s';
            app.MraylLabel_2 = uilabel(app.MediumparameterPanel_3);
            app.MraylLabel_2.Text = 'Mrayl';
            app.impedanceEditField_2Label = uilabel(app.MediumparameterPanel_3);
            app.impedanceEditField_2Label.Text = 'impedance';
            app.impedance_single = uieditfield(app.MediumparameterPanel_3, 'numeric');
            app.impedance_single.Value = 3270500;
            app.JKLabel_2 = uilabel(app.MediumparameterPanel_3);
            app.JKLabel_2.Text = 'J/K';
            app.WmKLabel_2 = uilabel(app.MediumparameterPanel_3);
            app.WmKLabel_2.Text = 'W/(m⋅K))';
            app.NapperLabel_2 = uilabel(app.MediumparameterPanel_3);
            app.NapperLabel_2.Text = 'Napper';
            app.conductivityEditField_3Label = uilabel(app.MediumparameterPanel_3);
            app.conductivityEditField_3Label.Text = 'conductivity';
            app.conductivity_single = uieditfield(app.MediumparameterPanel_3, 'numeric');
            app.conductivity_single.Value = 0.512;
            app.specificheatEditField_2Label = uilabel(app.MediumparameterPanel_3);
            app.specificheatEditField_2Label.Text = 'specific heat';
            app.specificheat_single = uieditfield(app.MediumparameterPanel_3, 'numeric');
            app.specificheat_single.Value = 3600;
            app.attenuationEditField_2Label = uilabel(app.MediumparameterPanel_3);
            app.attenuationEditField_2Label.Text = 'attenuation';
            app.attenuation_single = uieditfield(app.MediumparameterPanel_3, 'numeric');
            app.attenuation_single.Value = 36;
            app.MediumparameterPanel_4 = uipanel(app.SinglebubbleTab);
            app.MediumparameterPanel_4.Title = 'Medium parameter';
            app.gasexponentEditField_2Label = uilabel(app.MediumparameterPanel_4);
            app.gasexponentEditField_2Label.Text = 'gas exponent';
            app.gasexponen_single = uieditfield(app.MediumparameterPanel_4, 'numeric');
            app.gasexponen_single.Value = 0.051;
            app.surfacetensionEditField_2Label = uilabel(app.MediumparameterPanel_4);
            app.surfacetensionEditField_2Label.Text = 'surface tension';
            app.surfacetension_single = uieditfield(app.MediumparameterPanel_4, 'numeric');
            app.surfacetension_single.Value = 1.07;
            app.NmLabel_2 = uilabel(app.MediumparameterPanel_4);
            app.NmLabel_2.Text = 'N/m';
            app.Label_5 = uilabel(app.MediumparameterPanel_4);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.MediumparameterPanel_4);
            app.Label_6.Text = '';
            app.shearviscosityEditField_2Label = uilabel(app.MediumparameterPanel_4);
            app.shearviscosityEditField_2Label.Text = 'shear viscosity';
            app.shearviscosity_single = uieditfield(app.MediumparameterPanel_4, 'numeric');
            app.shearviscosity_single.Value = 0.4;
            app.umLabel_2 = uilabel(app.MediumparameterPanel_4);
            app.umLabel_2.Text = 'um';
            app.Label_7 = uilabel(app.MediumparameterPanel_4);
            app.Label_7.Text = '';
            app.umLabel_3 = uilabel(app.MediumparameterPanel_4);
            app.umLabel_3.Text = 'um';
            app.radiusEditField_2Label = uilabel(app.MediumparameterPanel_4);
            app.radiusEditField_2Label.Text = 'radius';
            app.radius_single = uieditfield(app.MediumparameterPanel_4, 'numeric');
            app.radius_single.Value = 2.5;
            app.distanceEditFieldLabel = uilabel(app.MediumparameterPanel_4);
            app.distanceEditFieldLabel.Text = 'distance';
            app.distance_single = uieditfield(app.MediumparameterPanel_4, 'numeric');
            app.distance_single.Value = 48;
            app.startButton_single = uibutton(app.SinglebubbleTab, 'push');
            app.startButton_single.ButtonPushedFcn = createCallbackFcn(app, @startButton_singlePushed, true);
            app.startButton_single.Text = 'start';
            app.BubblecloudTab = uitab(app.TabGroup);
            app.BubblecloudTab.Title = 'Bubble cloud';
            app.HIFUparameterPanel = uipanel(app.BubblecloudTab);
            app.HIFUparameterPanel.Title = 'HIFU parameter';
            app.frequencyEditFieldLabel = uilabel(app.HIFUparameterPanel);
            app.frequencyEditFieldLabel.Text = 'frequency';
            app.frequency_cloud = uieditfield(app.HIFUparameterPanel, 'numeric');
            app.frequency_cloud.Value = 4;
            app.pressureEditFieldLabel = uilabel(app.HIFUparameterPanel);
            app.pressureEditFieldLabel.Text = 'pressure';
            app.pressure_cloud = uieditfield(app.HIFUparameterPanel, 'numeric');
            app.pressure_cloud.Value = 1.2;
            app.MPaLabel = uilabel(app.HIFUparameterPanel);
            app.MPaLabel.Text = 'MPa';
            app.MHzLabel = uilabel(app.HIFUparameterPanel);
            app.MHzLabel.Text = 'MHz';
            app.MediumparameterPanel = uipanel(app.BubblecloudTab);
            app.MediumparameterPanel.Title = 'Medium parameter';
            app.velocityLabel = uilabel(app.MediumparameterPanel);
            app.velocityLabel.Text = 'velocity';
            app.velosity_cloud = uieditfield(app.MediumparameterPanel, 'numeric');
            app.velosity_cloud.Value = 1550;
            app.rhoLabel = uilabel(app.MediumparameterPanel);
            app.rhoLabel.Text = 'rho';
            app.Rho_cloud = uieditfield(app.MediumparameterPanel, 'numeric');
            app.Rho_cloud.Value = 1055;
            app.kgm3Label = uilabel(app.MediumparameterPanel);
            app.kgm3Label.Text = 'kg/m3';
            app.msLabel = uilabel(app.MediumparameterPanel);
            app.msLabel.Text = 'm/s';
            app.raylLabel = uilabel(app.MediumparameterPanel);
            app.raylLabel.Text = 'rayl';
            app.impedanceEditFieldLabel = uilabel(app.MediumparameterPanel);
            app.impedanceEditFieldLabel.Text = 'impedance';
            app.impedance_cloud = uieditfield(app.MediumparameterPanel, 'numeric');
            app.impedance_cloud.Value = 3270500;
            app.JKLabel = uilabel(app.MediumparameterPanel);
            app.JKLabel.Text = 'J/K';
            app.WmKLabel = uilabel(app.MediumparameterPanel);
            app.WmKLabel.Text = 'W/(m⋅K))';
            app.NapperLabel = uilabel(app.MediumparameterPanel);
            app.NapperLabel.Text = 'Napper';
            app.conductivityEditFieldLabel = uilabel(app.MediumparameterPanel);
            app.conductivityEditFieldLabel.Text = 'conductivity';
            app.conductivity_cloud = uieditfield(app.MediumparameterPanel, 'numeric');
            app.conductivity_cloud.Value = 0.512;
            app.specificheatEditFieldLabel = uilabel(app.MediumparameterPanel);
            app.specificheatEditFieldLabel.Text = 'specific heat';
            app.specificheat_cloud = uieditfield(app.MediumparameterPanel, 'numeric');
            app.specificheat_cloud.Value = 3600;
            app.attenuationEditFieldLabel = uilabel(app.MediumparameterPanel);
            app.attenuationEditFieldLabel.Text = 'attenuation';
            app.attenuationEditField = uieditfield(app.MediumparameterPanel, 'numeric');
            app.attenuationEditField.Value = 36;
            app.MediumparameterPanel_2 = uipanel(app.BubblecloudTab);
            app.MediumparameterPanel_2.Title = 'Medium parameter';
            app.gasexponentEditFieldLabel = uilabel(app.MediumparameterPanel_2);
            app.gasexponentEditFieldLabel.Text = 'gas exponent';
            app.gasexponent_cloud = uieditfield(app.MediumparameterPanel_2, 'numeric');
            app.gasexponent_cloud.Value = 0.051;
            app.surfacetensionEditFieldLabel = uilabel(app.MediumparameterPanel_2);
            app.surfacetensionEditFieldLabel.Text = 'surface tension';
            app.surfacetension_cloud = uieditfield(app.MediumparameterPanel_2, 'numeric');
            app.surfacetension_cloud.Value = 1.07;
            app.NmLabel = uilabel(app.MediumparameterPanel_2);
            app.NmLabel.Text = 'N/m';
            app.Label = uilabel(app.MediumparameterPanel_2);
            app.Label.Text = '';
            app.Label_4 = uilabel(app.MediumparameterPanel_2);
            app.Label_4.Text = '';
            app.shearviscosityEditFieldLabel = uilabel(app.MediumparameterPanel_2);
            app.shearviscosityEditFieldLabel.Text = 'shear viscosity';
            app.shearviscosity_cloud = uieditfield(app.MediumparameterPanel_2, 'numeric');
            app.shearviscosity_cloud.Value = 0.4;
            app.umLabel = uilabel(app.MediumparameterPanel_2);
            app.umLabel.Text = 'um';
            app.Label_2 = uilabel(app.MediumparameterPanel_2);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.MediumparameterPanel_2);
            app.Label_3.Text = '%';
            app.solutionratioEditFieldLabel = uilabel(app.MediumparameterPanel_2);
            app.solutionratioEditFieldLabel.Text = 'solution ratio';
            app.solutionratio_cloud = uieditfield(app.MediumparameterPanel_2, 'numeric');
            app.solutionratio_cloud.Value = 10;
            app.radiusEditFieldLabel = uilabel(app.MediumparameterPanel_2);
            app.radiusEditFieldLabel.Text = 'radius';
            app.radius_cloud = uieditfield(app.MediumparameterPanel_2, 'numeric');
            app.radius_cloud.Value = 2.5;
            app.SphereradiusEditFieldLabel = uilabel(app.BubblecloudTab);
            app.SphereradiusEditFieldLabel.Text = 'Sphere radius';
            app.Sphereradius_cloud = uieditfield(app.BubblecloudTab, 'numeric');
            app.Sphereradius_cloud.ValueChangedFcn = createCallbackFcn(app, @Sphereradius_cloudValueChanged, true);
            app.Sphereradius_cloud.Value = 2000;
            app.CubedistanceEditFieldLabel = uilabel(app.BubblecloudTab);
            app.CubedistanceEditFieldLabel.Text = 'Cube distance';
            app.Cubedistance_cloud = uieditfield(app.BubblecloudTab, 'numeric');
            app.Cubedistance_cloud.ValueChangedFcn = createCallbackFcn(app, @Cubedistance_cloudValueChanged, true);
            app.Cubedistance_cloud.Value = 1447;
            app.startButton_cloud = uibutton(app.BubblecloudTab, 'push');
            app.startButton_cloud.ButtonPushedFcn = createCallbackFcn(app, @startButton_cloudPushed, true);
            app.startButton_cloud.Text = 'start';
            app.SpherepressureLabel = uilabel(app.BubblecloudTab);
            app.SpherepressureLabel.Text = 'Sphere pressure';
            app.Spherepressure_cloud = uieditfield(app.BubblecloudTab, 'numeric');
            app.Spherepressure_cloud.ValueDisplayFormat = '%.3f';
            app.CubepressureLabel = uilabel(app.BubblecloudTab);
            app.CubepressureLabel.Text = 'Cube  pressure';
            app.Cubepressure_cloud = uieditfield(app.BubblecloudTab, 'numeric');
            app.Cubepressure_cloud.ValueDisplayFormat = '%.3f';
            app.umLabel_4 = uilabel(app.BubblecloudTab);
            app.umLabel_4.Text = 'um';
            app.umLabel_5 = uilabel(app.BubblecloudTab);
            app.umLabel_5.Text = 'um';
            app.MPaLabel_4 = uilabel(app.BubblecloudTab);
            app.MPaLabel_4.Text = 'MPa';
            app.MPaLabel_5 = uilabel(app.BubblecloudTab);
            app.MPaLabel_5.Text = 'MPa';
            app.changeButton = uibutton(app.Microbubble_thermal_simaulation_program, 'push');
            app.changeButton.ButtonPushedFcn = createCallbackFcn(app, @changeButtonPushed, true);
            app.changeButton.Text = 'change';
            app.imagesavingFolderEditFieldLabel = uilabel(app.Microbubble_thermal_simaulation_program);
            app.imagesavingFolderEditFieldLabel.Text = 'image saving Folder';
            app.imagesavingFolder = uieditfield(app.Microbubble_thermal_simaulation_program, 'text');
            app.sLabel = uilabel(app.Microbubble_thermal_simaulation_program);
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.Microbubble_thermal_simaulation_program);
            app.sLabel_2.Text = 's';
            app.SphereelapsedtimeLabel = uilabel(app.Microbubble_thermal_simaulation_program);
            app.SphereelapsedtimeLabel.Text = 'Sphere elapsed time';
            app.Sphereelapsedtime_cloud = uieditfield(app.Microbubble_thermal_simaulation_program, 'numeric');
            app.Sphereelapsedtime_cloud.ValueDisplayFormat = '%.3f';
            app.Sphereelapsedtime_cloud.Value = 2000;
            app.CubeelapsedtimeLabel = uilabel(app.Microbubble_thermal_simaulation_program);
            app.CubeelapsedtimeLabel.Text = 'Cube elapsed time';
            app.Cubeelapsedtim_cloud = uieditfield(app.Microbubble_thermal_simaulation_program, 'numeric');
            app.Cubeelapsedtim_cloud.ValueDisplayFormat = '%.3f';
            app.Cubeelapsedtim_cloud.Value = 1447;
            % Show the figure after all components are created
            app.Microbubble_thermal_simaulation_program.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bubble

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Microbubble_thermal_simaulation_program)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Microbubble_thermal_simaulation_program)
        end
    end
end